classdef DirectoryUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        WarningUIFigure  matlab.ui.Figure
        ContinueButton   matlab.ui.control.Button
        CancelButton     matlab.ui.control.Button
    end

    
    properties (Access = private)
        directory % Description
    end
    
    properties (Access = public)
        proceed = false % Description
        done = false % Description
    end
    
    methods (Access = public)
        
        function CloseTheWindow(app)
            app.delete();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, directory)
            app.directory = directory;
            msg = [directory;...
                "This file already contains data.";...
                "Do you wish to overwrite it?"];
            uilabel(app.WarningUIFigure, ...
                'Text', msg, ...
                'Position', [50 60 400 60])
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            if exist(app.directory, 'dir')
                rmdir(app.directory, 's' );
            end
            app.proceed = true;
            app.done = true;
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.done = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.WarningUIFigure = uifigure('Visible', 'off');
            app.WarningUIFigure.Name = 'Warning ';
            app.ContinueButton = uibutton(app.WarningUIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.CancelButton = uibutton(app.WarningUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.WarningUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DirectoryUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.WarningUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.WarningUIFigure)
        end
    end
end